classdef MemoryMeds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PrescriptionsTab             matlab.ui.container.Tab
        Prescription_Subfield_Tabs   matlab.ui.container.TabGroup
        AddTab                       matlab.ui.container.Tab
        NotesTextArea                matlab.ui.control.TextArea
        ExpirationEditField          matlab.ui.control.EditField
        ExpirationEditFieldLabel     matlab.ui.control.Label
        DatePrescribedEditField      matlab.ui.control.EditField
        DosageEditField              matlab.ui.control.EditField
        DrugNameEditField            matlab.ui.control.EditField
        DrugNameEditFieldLabel       matlab.ui.control.Label
        DosageEditFieldLabel         matlab.ui.control.Label
        DatePrescribedLabel          matlab.ui.control.Label
        NotesTextAreaLabel           matlab.ui.control.Label
        uploadButton                 matlab.ui.control.Button
        EditButton                   matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        Image                        matlab.ui.control.Image
        HistoryTab                   matlab.ui.container.Tab
        UITable                      matlab.ui.control.Table
        Image_2                      matlab.ui.control.Image
        VaccinationsTab              matlab.ui.container.Tab
        Vaccination_Subfield_Tabs    matlab.ui.container.TabGroup
        AddTab_2                     matlab.ui.container.Tab
        TextArea                     matlab.ui.control.TextArea
        NotesTextArea_2              matlab.ui.control.TextArea
        NotesTextArea_2Label         matlab.ui.control.Label
        DateGivenEditField           matlab.ui.control.EditField
        DateGivenEditFieldLabel      matlab.ui.control.Label
        NameofVaccineEditField       matlab.ui.control.EditField
        NameofVaccineEditFieldLabel  matlab.ui.control.Label
        EditButton_2                 matlab.ui.control.Button
        ApplyButton_2                matlab.ui.control.Button
        HistoryTab_2                 matlab.ui.container.Tab
        UITable_2                    matlab.ui.control.Table
        AccountTab                   matlab.ui.container.Tab
        SurgicalHistoryTab           matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        AddTab_3                     matlab.ui.container.Tab
        ComplicationsTextArea        matlab.ui.control.TextArea
        ComplicationsTextAreaLabel   matlab.ui.control.Label
        DateofSurgeryEditField       matlab.ui.control.EditField
        DateofSurgeryEditFieldLabel  matlab.ui.control.Label
        SurgeryEditField             matlab.ui.control.EditField
        SurgeryEditFieldLabel        matlab.ui.control.Label
        EditButton_3                 matlab.ui.control.Button
        ApplyButton_3                matlab.ui.control.Button
        HistoryTab_3                 matlab.ui.container.Tab
        UITable_3                    matlab.ui.control.Table
        SettingsLabel                matlab.ui.control.Label
        PrescriptionsLabel           matlab.ui.control.Label
        VaccinationsLabel            matlab.ui.control.Label
        AccountButton                matlab.ui.control.Button
        PrescriptionsButton          matlab.ui.control.Button
        VaccinationsButton           matlab.ui.control.Button
        SettingsButton               matlab.ui.control.Button
    end

    
    properties (Access = public)
        RowCount = 1;
        RowCount_2 = 1;
        RowCount_3 = 1;
        ImagePath = "C:\Users\joser\Desktop\lkl\MedHack_Yayz!\RX.png";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.UIFigure.Units = "normalized";
%             app.UIFigure.Position = [0.3, 0.2, 0.4, 0.5];
%             app.UIFigure.Units = "pixels";
% 
%             app.TabGroup.Units= "normalized";
%             app.TabGroup.Position = [0, 0, 1, 1];
%             app.TabGroup.Units = "pixels";
        end

        % Button pushed function: PrescriptionsButton
        function PrescriptionsButtonPushed(app, event)
            app.TabGroup.Visible = "on";
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.NotesTextArea.Editable = "off";
            app.DrugNameEditField.Editable = "off";
            app.DatePrescribedEditField.Editable = "off";
            app.DosageEditField.Editable = "off";
            app.ExpirationEditField.Editable = "off";
          
            app.EditButton.Enable = "on";
            app.uploadButton.Enable = "off";
            app.ApplyButton.Enable = "off";

            if isempty(app.UITable.Data)
                app.UITable.Data = "";
                app.UITable.Data(app.RowCount,1) = app.ImagePath;
                app.UITable.Data(app.RowCount,2) = app.DrugNameEditField.Value;
                app.UITable.Data(app.RowCount,3) = app.DatePrescribedEditField.Value;
                app.UITable.Data(app.RowCount,4) = app.DosageEditField.Value;
                app.UITable.Data(app.RowCount,5) = app.ExpirationEditField.Value;
                app.UITable.Data(app.RowCount,6) = app.NotesTextArea.Value;
            else
                app.RowCount = size(app.UITable.Data,1)+1;
                app.UITable.Data(app.RowCount,1) = app.ImagePath;
                app.UITable.Data(app.RowCount,2) = app.DrugNameEditField.Value;
                app.UITable.Data(app.RowCount,3) = app.DatePrescribedEditField.Value;
                app.UITable.Data(app.RowCount,4) = app.DosageEditField.Value;
                app.UITable.Data(app.RowCount,5) = app.ExpirationEditField.Value;
                app.UITable.Data(app.RowCount,6) = app.NotesTextArea.Value;
            end
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            app.NotesTextArea.Editable = "on";
            app.DrugNameEditField.Editable = "on";
            app.DatePrescribedEditField.Editable = "on";
            app.DosageEditField.Editable = "on";
            app.ExpirationEditField.Editable = "on";

            app.EditButton.Enable = "off";
            app.uploadButton.Enable = "on";
            app.ApplyButton.Enable = "on";
        end

        % Button pushed function: uploadButton
        function uploadButtonPushed(app, event)
            filter = {'*.png','*.jpeg','*.jpg','*.tif','*.svg'}';
            [filename,folder] = uigetfile(filter);
            app.ImagePath = string(fullfile(folder,filename));
            Img = imread(app.ImagePath);
            app.Image.ImageSource =  Img;         
            
        end

        % Selection changed function: UITable
        function UITableSelectionChanged(app, event)
            indices = app.UITable.Selection;
            
            app.Image_2.ImageSource = imread(app.UITable.Data(indices(1,1),1));
        end

        % Button pushed function: ApplyButton_2
        function ApplyButton_2Pushed(app, event)
            app.NameofVaccineEditField.Editable = "off";
            app.DateGivenEditField.Editable = "off";
            app.NotesTextArea_2.Editable = "off";
            app.EditButton_2.Enable = "on";
            app.ApplyButton_2.Enable = "off";
            app.Vaccination_Subfield_Tabs.SelectedTab = app.HistoryTab_2;

            if isempty(app.UITable_2.Data)
                app.UITable_2.Data = "";
                app.UITable_2.Data(app.RowCount_2,1) = app.NameofVaccineEditField.Value;
                app.UITable_2.Data(app.RowCount_2,2) = app.DateGivenEditField.Value;
                app.UITable_2.Data(app.RowCount_2,3) = app.NotesTextArea_2.Value;
            else
                app.RowCount_2 = size(app.UITable_2.Data,1)+1;
                app.UITable_2.Data(app.RowCount_2,1) = app.NameofVaccineEditField.Value;
                app.UITable_2.Data(app.RowCount_2,2) = app.DateGivenEditField.Value;
                app.UITable_2.Data(app.RowCount_2,3) = app.NotesTextArea_2.Value;
            end
        end

        % Button pushed function: EditButton_2
        function EditButton_2Pushed(app, event)
            app.NameofVaccineEditField.Editable = "on";
            app.DateGivenEditField.Editable = "on";
            app.NotesTextArea_2.Editable = "on";
            app.ApplyButton_2.Enable = "on";
            app.EditButton_2.Enable = "off";
        end

        % Button pushed function: VaccinationsButton
        function VaccinationsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.VaccinationsTab;
            app.TabGroup.Visible ="on";
        end

        % Button pushed function: ApplyButton_3
        function ApplyButton_3Pushed(app, event)
            app.SurgeryEditField.Editable = "off";
            app.DateofSurgeryEditField.Editable = "off";
            app.ComplicationsTextArea.Editable = "off";
            app.EditButton_3.Enable = "on";
            app.ApplyButton_3.Enable = "off";
            app.TabGroup2.SelectedTab = app.HistoryTab_3;
                
             if isempty(app.UITable_3.Data)
                    app.UITable_3.Data = "";
                    app.UITable_3.Data(app.RowCount_3,1) = app.SurgeryEditField.Value;
                    app.UITable_3.Data(app.RowCount_3,2) = app.DateofSurgeryEditField.Value;
                    app.UITable_3.Data(app.RowCount_3,3) = app.ComplicationsTextArea.Value;
            else
                    app.RowCount_3 = size(app.UITable_3.Data,1)+1;
                    app.UITable_3.Data(app.RowCount_3,1) = app.SurgeryEditField.Value;
                    app.UITable_3.Data(app.RowCount_3,2) = app.DateofSurgeryEditField.Value;
                 app.UITable_3.Data(app.RowCount_3,3) = app.ComplicationsTextArea.Value;
            end
        end

        % Button pushed function: EditButton_3
        function EditButton_3Pushed(app, event)
            app.SurgeryEditField.Editable = "on";
            app.DateofSurgeryEditField.Editable = "on";
            app.ComplicationsTextArea.Editable = "on";
            app.ApplyButton_3.Enable = "on";
            app.EditButton_3.Enable = "off";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SettingsButton = uibutton(app.UIFigure, 'push');
            app.SettingsButton.Icon = fullfile(pathToMLAPP, 'Settings.PNG');
            app.SettingsButton.Text = '';
            app.VaccinationsButton = uibutton(app.UIFigure, 'push');
            app.VaccinationsButton.ButtonPushedFcn = createCallbackFcn(app, @VaccinationsButtonPushed, true);
            app.VaccinationsButton.Icon = fullfile(pathToMLAPP, 'MaskIcon.png');
            app.VaccinationsButton.Text = '';
            app.PrescriptionsButton = uibutton(app.UIFigure, 'push');
            app.PrescriptionsButton.ButtonPushedFcn = createCallbackFcn(app, @PrescriptionsButtonPushed, true);
            app.PrescriptionsButton.Icon = fullfile(pathToMLAPP, 'RX.png');
            app.PrescriptionsButton.Text = '';
            app.AccountButton = uibutton(app.UIFigure, 'push');
            app.AccountButton.Icon = fullfile(pathToMLAPP, 'PeopleCirclePlusIcon.png');
            app.AccountButton.Text = '';
            app.VaccinationsLabel = uilabel(app.UIFigure);
            app.VaccinationsLabel.Text = 'Vaccinations';
            app.PrescriptionsLabel = uilabel(app.UIFigure);
            app.PrescriptionsLabel.Text = 'Prescriptions';
            app.SettingsLabel = uilabel(app.UIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Visible = 'off';
            app.PrescriptionsTab = uitab(app.TabGroup);
            app.PrescriptionsTab.Title = 'Prescriptions';
            app.Prescription_Subfield_Tabs = uitabgroup(app.PrescriptionsTab);
            app.AddTab = uitab(app.Prescription_Subfield_Tabs);
            app.AddTab.Title = 'Add';
            app.Image = uiimage(app.AddTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RX.png');
            app.ApplyButton = uibutton(app.AddTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.EditButton = uibutton(app.AddTab, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.uploadButton = uibutton(app.AddTab, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @uploadButtonPushed, true);
            app.uploadButton.Icon = 'file_open.png';
            app.uploadButton.Text = 'upload';
            app.NotesTextAreaLabel = uilabel(app.AddTab);
            app.NotesTextAreaLabel.Text = 'Notes:';
            app.DatePrescribedLabel = uilabel(app.AddTab);
            app.DatePrescribedLabel.Text = 'Date Prescribed';
            app.DosageEditFieldLabel = uilabel(app.AddTab);
            app.DosageEditFieldLabel.Text = 'Dosage';
            app.DrugNameEditFieldLabel = uilabel(app.AddTab);
            app.DrugNameEditFieldLabel.Text = 'Drug Name';
            app.DrugNameEditField = uieditfield(app.AddTab, 'text');
            app.DrugNameEditField.Value = 'Tylenol';
            app.DosageEditField = uieditfield(app.AddTab, 'text');
            app.DosageEditField.Value = '100 mg';
            app.DatePrescribedEditField = uieditfield(app.AddTab, 'text');
            app.DatePrescribedEditField.Value = '09/10/2022';
            app.ExpirationEditFieldLabel = uilabel(app.AddTab);
            app.ExpirationEditFieldLabel.Text = 'Expiration';
            app.ExpirationEditField = uieditfield(app.AddTab, 'text');
            app.ExpirationEditField.Value = '12/10/2022';
            app.NotesTextArea = uitextarea(app.AddTab);
            app.NotesTextArea.Value = {'Dr. Kevin Holly monitored the patient.'};
            app.HistoryTab = uitab(app.Prescription_Subfield_Tabs);
            app.HistoryTab.Title = 'History';
            app.Image_2 = uiimage(app.HistoryTab);
            app.UITable = uitable(app.HistoryTab);
            app.UITable.ColumnName = {'ImageFile'; 'Drug Name'; 'Dosage'; 'Date Presicribed'; 'Expriration'; 'Notes'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true true true false];
            app.UITable.ColumnEditable = [false true true true true true];
            app.UITable.SelectionChangedFcn = createCallbackFcn(app, @UITableSelectionChanged, true);
            app.VaccinationsTab = uitab(app.TabGroup);
            app.VaccinationsTab.Title = 'Vaccinations';
            app.Vaccination_Subfield_Tabs = uitabgroup(app.VaccinationsTab);
            app.AddTab_2 = uitab(app.Vaccination_Subfield_Tabs);
            app.AddTab_2.Title = 'Add';
            app.ApplyButton_2 = uibutton(app.AddTab_2, 'push');
            app.ApplyButton_2.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_2Pushed, true);
            app.ApplyButton_2.Text = 'Apply';
            app.EditButton_2 = uibutton(app.AddTab_2, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditButton_2Pushed, true);
            app.EditButton_2.Text = 'Edit';
            app.NameofVaccineEditFieldLabel = uilabel(app.AddTab_2);
            app.NameofVaccineEditFieldLabel.Text = 'Name of Vaccine';
            app.NameofVaccineEditField = uieditfield(app.AddTab_2, 'text');
            app.NameofVaccineEditField.Value = 'Default';
            app.DateGivenEditFieldLabel = uilabel(app.AddTab_2);
            app.DateGivenEditFieldLabel.Text = 'Date Given';
            app.DateGivenEditField = uieditfield(app.AddTab_2, 'text');
            app.DateGivenEditField.Value = 'Default';
            app.NotesTextArea_2Label = uilabel(app.AddTab_2);
            app.NotesTextArea_2Label.Text = 'Notes:';
            app.NotesTextArea_2 = uitextarea(app.AddTab_2);
            app.NotesTextArea_2.Value = {'Default'};
            app.TextArea = uitextarea(app.AddTab_2);
            app.TextArea.Value = {'Vaccination'};
            app.HistoryTab_2 = uitab(app.Vaccination_Subfield_Tabs);
            app.HistoryTab_2.Title = 'History';
            app.UITable_2 = uitable(app.HistoryTab_2);
            app.UITable_2.ColumnName = {'Name'; 'Date Given'; 'Notes'};
            app.UITable_2.RowName = {};
            app.UITable_2.ColumnSortable = [true true];
            app.UITable_2.ColumnEditable = [true true true];
            app.AccountTab = uitab(app.TabGroup);
            app.AccountTab.Title = 'Account ';
            app.SurgicalHistoryTab = uitab(app.TabGroup);
            app.SurgicalHistoryTab.Title = 'Surgical History';
            app.TabGroup2 = uitabgroup(app.SurgicalHistoryTab);
            app.AddTab_3 = uitab(app.TabGroup2);
            app.AddTab_3.Title = 'Add';
            app.ApplyButton_3 = uibutton(app.AddTab_3, 'push');
            app.ApplyButton_3.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_3Pushed, true);
            app.ApplyButton_3.Text = 'Apply';
            app.EditButton_3 = uibutton(app.AddTab_3, 'push');
            app.EditButton_3.ButtonPushedFcn = createCallbackFcn(app, @EditButton_3Pushed, true);
            app.EditButton_3.Text = 'Edit';
            app.SurgeryEditFieldLabel = uilabel(app.AddTab_3);
            app.SurgeryEditFieldLabel.Text = 'Surgery';
            app.SurgeryEditField = uieditfield(app.AddTab_3, 'text');
            app.SurgeryEditField.Value = 'Default';
            app.DateofSurgeryEditFieldLabel = uilabel(app.AddTab_3);
            app.DateofSurgeryEditFieldLabel.Text = 'Date of Surgery';
            app.DateofSurgeryEditField = uieditfield(app.AddTab_3, 'text');
            app.DateofSurgeryEditField.Value = 'Default';
            app.ComplicationsTextAreaLabel = uilabel(app.AddTab_3);
            app.ComplicationsTextAreaLabel.Text = 'Complications';
            app.ComplicationsTextArea = uitextarea(app.AddTab_3);
            app.ComplicationsTextArea.Value = {'Default'};
            app.HistoryTab_3 = uitab(app.TabGroup2);
            app.HistoryTab_3.Title = 'History';
            app.UITable_3 = uitable(app.HistoryTab_3);
            app.UITable_3.ColumnName = {'Surgery '; 'Surgery Date'; 'Complications'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnSortable = [true true];
            app.UITable_3.ColumnEditable = [true true true];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MemoryMeds

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end